classdef instrumentacion1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        TABLATab                       matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        BUSCARARCHIVO                  matlab.ui.control.Button
        MEDIAEditFieldLabel            matlab.ui.control.Label
        MEDIA                          matlab.ui.control.EditField
        VARIANZAEditFieldLabel         matlab.ui.control.Label
        V1                             matlab.ui.control.EditField
        DESVIACIONESTANDAREditFieldLabel  matlab.ui.control.Label
        D1                             matlab.ui.control.EditField
        VARIANZADELAMEDIAEditFieldLabel  matlab.ui.control.Label
        V2                             matlab.ui.control.EditField
        DESVIACIONDELAMEDIALabel       matlab.ui.control.Label
        D2                             matlab.ui.control.EditField
        INCERTIDUMBREEditFieldLabel_2  matlab.ui.control.Label
        I                              matlab.ui.control.EditField
        C                              matlab.ui.control.Button
        Tab2                           matlab.ui.container.Tab
        Button                         matlab.ui.control.Button
        A                              matlab.ui.control.UIAxes
        Button2                        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BUSCARARCHIVO
        function BUSCARARCHIVOPushed(app, event)
            global X
            [nombre,ruta] = uigetfile('*.XLSX');
            if nombre == 0
                return;
            else
                datos =strcat(ruta,nombre);
                
                X = readmatrix('DATOS.XLSX');
                X(find(X==0))=[]
                
                suma=sum(X);
                length(X);
                length(X)-1;
                denominador=(length(X)*(length(X)-1));
                p=suma/length(X);
                
                PROMEDIO=p
                var(X);
                VARIANZA=var(X)
                DESVIACION=sqrt(var(X))
                VARIANZA2=(var(X)/length(X))
                DESVIACION2=sqrt((var(X)/length(X)))
                
                resta=(X-PROMEDIO);
                N=resta.^2;
                suma2=sum(N);
                INCERTIDUMBREESTANDAR=sqrt(suma2/denominador)
                
                
                app.UITable.Data = readtable("DATOS.xlsx");
                
                
            end
        end

        % Button pushed function: C
        function CPushed(app, event)
            global X
            
            suma=sum(X);
            length(X);
            length(X)-1;
            denominador=(length(X)*(length(X)-1));
            p=suma/length(X);
            
            PROMEDIO=p
            var(X);
            VARIANZA=var(X)
            DESVIACION=sqrt(var(X))
            VARIANZA2=(var(X)/length(X))
            DESVIACION2=sqrt((var(X)/length(X)))
            
            resta=(X-PROMEDIO);
            N=resta.^2;
            suma2=sum(N);
            INCERTIDUMBREESTANDAR=sqrt(suma2/denominador)


            app.MEDIA.Value = num2str(PROMEDIO);
            app.V1.Value = num2str(VARIANZA);
            app.D1.Value = num2str(DESVIACION);
            app.V2.Value = num2str(VARIANZA2);
            app.D2.Value = num2str(DESVIACION2);
            app.I.Value = num2str(INCERTIDUMBREESTANDAR);
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global X
            plot(app.A,X)
            
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            global X
            
            suma=sum(X);
            length(X);
            p=suma/length(X);
            t=1:1:98;
            %d=DESVIACION
            y=p
            m=X
            
            v=ones(1,98);
            v1=y.*v;
            v3=m.*v;
            plot(app.A,t,v3,'b*',t,v1)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TABLATab = uitab(app.TabGroup);
            app.TABLATab.Title = 'TABLA';
            app.UITable = uitable(app.TABLATab);
            app.UITable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.BUSCARARCHIVO = uibutton(app.TABLATab, 'push');
            app.BUSCARARCHIVO.ButtonPushedFcn = createCallbackFcn(app, @BUSCARARCHIVOPushed, true);
            app.BUSCARARCHIVO.Text = 'BUSCAR ARCHIVO';
            app.MEDIAEditFieldLabel = uilabel(app.TABLATab);
            app.MEDIAEditFieldLabel.Text = 'MEDIA';
            app.MEDIA = uieditfield(app.TABLATab, 'text');
            app.VARIANZAEditFieldLabel = uilabel(app.TABLATab);
            app.VARIANZAEditFieldLabel.Text = 'VARIANZA';
            app.V1 = uieditfield(app.TABLATab, 'text');
            app.DESVIACIONESTANDAREditFieldLabel = uilabel(app.TABLATab);
            app.DESVIACIONESTANDAREditFieldLabel.Text = 'DESVIACION ESTANDAR';
            app.D1 = uieditfield(app.TABLATab, 'text');
            app.VARIANZADELAMEDIAEditFieldLabel = uilabel(app.TABLATab);
            app.VARIANZADELAMEDIAEditFieldLabel.Text = 'VARIANZA DE LA MEDIA';
            app.V2 = uieditfield(app.TABLATab, 'text');
            app.DESVIACIONDELAMEDIALabel = uilabel(app.TABLATab);
            app.DESVIACIONDELAMEDIALabel.Text = 'DESVIACION DE LA MEDIA';
            app.D2 = uieditfield(app.TABLATab, 'text');
            app.INCERTIDUMBREEditFieldLabel_2 = uilabel(app.TABLATab);
            app.INCERTIDUMBREEditFieldLabel_2.Text = 'INCERTIDUMBRE';
            app.I = uieditfield(app.TABLATab, 'text');
            app.C = uibutton(app.TABLATab, 'push');
            app.C.ButtonPushedFcn = createCallbackFcn(app, @CPushed, true);
            app.C.Text = 'CALCULAR';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Button = uibutton(app.Tab2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.A = uiaxes(app.Tab2);
            title(app.A, 'Title')
            xlabel(app.A, 'X')
            ylabel(app.A, 'Y')
            app.Button2 = uibutton(app.Tab2, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = instrumentacion1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end